classdef perturbationSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Image                matlab.ui.control.Image
        text7                matlab.ui.control.Label
        uipanel2             matlab.ui.container.Panel
        text13               matlab.ui.control.Label
        text12               matlab.ui.control.Label
        text10               matlab.ui.control.Label
        text8                matlab.ui.control.Label
        w3_edit              matlab.ui.control.NumericEditField
        w2_edit              matlab.ui.control.NumericEditField
        w1_edit              matlab.ui.control.NumericEditField
        A_edit               matlab.ui.control.NumericEditField
        miscPer_pushbutton   matlab.ui.control.Button
        uipanel1             matlab.ui.container.Panel
        Tgz_edit             matlab.ui.control.NumericEditField
        Tgy_edit             matlab.ui.control.NumericEditField
        Tgx_edit             matlab.ui.control.NumericEditField
        constPer_pushbutton  matlab.ui.control.Button
        text6                matlab.ui.control.Label
        text5                matlab.ui.control.Label
        text4                matlab.ui.control.Label
        text3                matlab.ui.control.Label
        text2                matlab.ui.control.Label
    end

    
    properties (Access = private)
        callMainApp % Description
    end
    
    methods (Access = private)
        
        function updateInterface(app,data)
            %%%Update constant disturbances
            app.Tgx_edit.Value = data.constant.Tdx;
            app.Tgy_edit.Value = data.constant.Tdy;
            app.Tgz_edit.Value = data.constant.Tdz;
            %%%Update variable disturbances
            app.A_edit.Value  = data.variable.A ;
            app.w1_edit.Value = data.variable.w1;
            app.w2_edit.Value = data.variable.w2;
            app.w3_edit.Value = data.variable.w3;
        end        
        
        function newData = updateParameters1(app)
            %%%Update inertia tensor
            newData.Tdx = app.Tgx_edit.Value;
            newData.Tdy = app.Tgy_edit.Value;
            newData.Tdz = app.Tgz_edit.Value; 
            newData.i = 2;
        end

        function newData = updateParameters2(app)
            %%%Update inertia tensor
            newData.A = app.A_edit.Value;
            newData.w1 = app.w1_edit.Value;
            newData.w2 = app.w2_edit.Value; 
            newData.w3 = app.w3_edit.Value; 
            newData.i = 3;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, mainAppData)
            app.callMainApp = mainApp;
            updateInterface(app, mainAppData);
        end

        % Button pushed function: constPer_pushbutton
        function constPer_pushbuttonButtonPushed(app, event)
            dataToSend = updateParameters1(app);
            importData(app.callMainApp,dataToSend);
            delete(app);
        end

        % Button pushed function: miscPer_pushbutton
        function miscPer_pushbuttonButtonPushed(app, event)
           dataToSend = updateParameters2(app);
           importData(app.callMainApp,dataToSend);
           delete(app); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.text2 = uilabel(app.UIFigure);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Perturbation Settings';
            app.uipanel1 = uipanel(app.UIFigure);
            app.uipanel1.Title = 'Constant Perturbation';
            app.uipanel1.Tag = 'uipanel1';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'X';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Y';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Z';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Perturbation (Nm):';
            app.constPer_pushbutton = uibutton(app.uipanel1, 'push');
            app.constPer_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @constPer_pushbuttonButtonPushed, true);
            app.constPer_pushbutton.Tag = 'constPer_pushbutton';
            app.constPer_pushbutton.Text = 'Apply';
            app.Tgx_edit = uieditfield(app.uipanel1, 'numeric');
            app.Tgx_edit.ValueDisplayFormat = '%.3f';
            app.Tgy_edit = uieditfield(app.uipanel1, 'numeric');
            app.Tgy_edit.ValueDisplayFormat = '%.3f';
            app.Tgz_edit = uieditfield(app.uipanel1, 'numeric');
            app.Tgz_edit.ValueDisplayFormat = '%.3f';
            app.uipanel2 = uipanel(app.UIFigure);
            app.uipanel2.Title = 'Miscellaneous Perturbation';
            app.uipanel2.Tag = 'uipanel2';
            app.miscPer_pushbutton = uibutton(app.uipanel2, 'push');
            app.miscPer_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @miscPer_pushbuttonButtonPushed, true);
            app.miscPer_pushbutton.Tag = 'miscPer_pushbutton';
            app.miscPer_pushbutton.Text = 'Apply';
            app.A_edit = uieditfield(app.uipanel2, 'numeric');
            app.A_edit.ValueDisplayFormat = '%.3f';
            app.w1_edit = uieditfield(app.uipanel2, 'numeric');
            app.w1_edit.ValueDisplayFormat = '%.3f';
            app.w2_edit = uieditfield(app.uipanel2, 'numeric');
            app.w2_edit.ValueDisplayFormat = '%.3f';
            app.w3_edit = uieditfield(app.uipanel2, 'numeric');
            app.w3_edit.ValueDisplayFormat = '%.3f';
            app.text8 = uilabel(app.uipanel2);
            app.text8.Tag = 'text8';
            app.text8.Text = '[ sin(';
            app.text10 = uilabel(app.uipanel2);
            app.text10.Tag = 'text10';
            app.text10.Text = 't), sin(';
            app.text12 = uilabel(app.uipanel2);
            app.text12.Tag = 'text12';
            app.text12.Text = 't), sin(';
            app.text13 = uilabel(app.uipanel2);
            app.text13.Tag = 'text13';
            app.text13.Text = 't)] ''';
            app.text7 = uilabel(app.UIFigure);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Perturbation (Nm):';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'adcs.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = perturbationSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end